classdef ReadSonarv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FileMenu               matlab.ui.container.Menu
        SaveMenu_2             matlab.ui.container.Menu
        LoadMenu_2             matlab.ui.container.Menu
        SettingsMenu           matlab.ui.container.Menu
        InitializeArduinoMenu  matlab.ui.container.Menu
        CloseArduinoMenu       matlab.ui.container.Menu
        PortMenu               matlab.ui.container.Menu
        ManualInputModeMenu    matlab.ui.container.Menu
        OrderMenu              matlab.ui.container.Menu
        ndMenu                 matlab.ui.container.Menu
        rdMenu                 matlab.ui.container.Menu
        thMenu                 matlab.ui.container.Menu
        ReadValuesButton       matlab.ui.control.Button
        ResetButton            matlab.ui.control.Button
        ContinuousButton       matlab.ui.control.Button
        Length1EditFieldLabel  matlab.ui.control.Label
        Length1EditField       matlab.ui.control.NumericEditField
        Length2EditFieldLabel  matlab.ui.control.Label
        Length2EditField       matlab.ui.control.NumericEditField
        Length3EditFieldLabel  matlab.ui.control.Label
        Length3EditField       matlab.ui.control.NumericEditField
        Period1EditFieldLabel  matlab.ui.control.Label
        Period1EditField       matlab.ui.control.NumericEditField
        Period2EditFieldLabel  matlab.ui.control.Label
        Period2EditField       matlab.ui.control.NumericEditField
        Period3EditFieldLabel  matlab.ui.control.Label
        Period3EditField       matlab.ui.control.NumericEditField
        Measure1Button         matlab.ui.control.Button
        Measure2Button         matlab.ui.control.Button
        Measure3Button         matlab.ui.control.Button
        MethodDropDownLabel    matlab.ui.control.Label
        MethodDropDown         matlab.ui.control.DropDown
        RunButton              matlab.ui.control.Button
        PeriodLEditFieldLabel  matlab.ui.control.Label
        PeriodLEditField       matlab.ui.control.EditField
        Switch                 matlab.ui.control.Switch
        AmplitudeKnobLabel     matlab.ui.control.Label
        AmplitudeKnob          matlab.ui.control.Knob
        FrequencyKnobLabel     matlab.ui.control.Label
        FrequencyKnob          matlab.ui.control.Knob
        PhaseKnobLabel         matlab.ui.control.Label
        PhaseKnob              matlab.ui.control.Knob
        YSliderLabel           matlab.ui.control.Label
        YSlider                matlab.ui.control.Slider
        UIAxes                 matlab.ui.control.UIAxes
        ErrorWindow            matlab.ui.control.UIAxes
    end

    properties (Access = public)
        Arduino =[]  % Hardware access to arduino
        UltraSound =[] % Access to Ultrasound hardware
        time = [];  %an artificial time step.  (use tic/toc for actual time)
        y = []; %storage of data.
        ymf = [];
        initialized = false;  %flag for hardware initialized.
        portlist = serialportlist("available")
        selectedport = '/dev/cu.usbmodem1101';
        list = [];  %List of available ports
        run = 0;    %Unused variable
        
        %Global Variables for Period
        period;     
        period1; 
        period2;
        period3;
        
        userPlot;               %Global variable for user adjusted sine function
        userx = 0:0.01:3;       %Values from time = 0sec to time = 3sec
        userf2 = 6;             %Frequency value
        usery = 0.12;           %Y-Value
        usera = 0.025;          %Amplitude Value    
        userph = -1.3;          %Phase-shift Value
        art_state = false;      %Check variable for art window
        er_array = [];          %Storage array for error values
        er_count = 1;           %Global counter for error iterations
    end
    
    methods (Access = private)
        
        function period = FindPeriod(app) %#ok<STOUT>
            %Finding the period
            c = 0;              %A counter for how many times there has been a sign change
            peak = 0;           %initial peak value
            
            for i = 5:9:200       
                if peak >= 3    %Third peak represents the stopping point of period
                    break
                end
                
                %Defining delta x, delta y
                y1 = app.ymf(i);
                x1 = app.time(i);
                
                y2 = app.ymf(i+1);
                x2 = app.time(i+1);
                
                yd = y2 - y1;
                xd = x2 - x1;
                
                if c == 0       %represents first iteration
                    if yd*xd >= 0
                        c = 1;  %represents positive slope
                    else
                        c = 2;  %represents negative slope
                    end
                end
                
                if c == 1
                    if yd*xd < 0
                        peak = peak + 1; %Add to peak value
                        if peak == 1
                            p1 = app.time(i);  %Set p1 value
                        elseif peak == 3
                            p2 = app.time(i);  %Set p2 value. p == 3 because we don't care about peak 2
                        end
                        c = 2;
                        continue
                    end
                end
                
                if c == 2
                    if yd*xd >= 0
                        peak = peak + 1;
                        if peak == 1
                            p1 = app.time-(i);
                        elseif peak == 3
                            p2 = app.time-(i);
                        end
                        c = 1;
                        continue
                    end
                end
            end
            
            %Assigning a value to the period
            app.period = p2 - p1;
        end
        
        %Main Numerical Method for model generation
        function er = ObjectiveFunc(app, g)
           s = 241;                                             %number of iterations based on number of data points gathered for a 3 second run
           error_sum = 0;
            for i = 1:s                                         %Loop through checking the Y values vs the supplied data
              m = g(1)*sin(g(2)*app.time(i) + g(3))+g(4);
              error_sum = error_sum + abs(app.y(i)-m);          
            end
            er = error_sum/s;                                   %Error calculation and Display   
            error_title = sprintf("Error = %.3f", er);
            pause(0.02);                                        %Pause time for algorithm timing
            title(app.UIAxes, error_title); 
            if app.art_state == false                           %Display figure window if not already present
                artfigure = figure;
                artfigure.Position = [668 668 500 500];         %Window position (may not work for all users)
            end
            %Count and store error values
            app.er_count = app.er_count + 1;
            app.er_array(app.er_count) = er;
            
            %Allow live updating of interface during algorithm run
            testm = g(1)*sin(g(2)*app.userx + g(3))+g(4);
            app.usera = g(1);
            app.AmplitudeKnob.Value = app.usera;
            
            app.userf2 = g(2);
            app.FrequencyKnob.Value = app.userf2;
            
            app.userph = g(3);
            app.PhaseKnob.Value = app.userph;
            
            app.usery = g(4);
            app.YSlider.Value = app.usery;
            
            delete(app.userPlot)
         
            hold (app.UIAxes, "on");
            app.userPlot = plot(app.UIAxes, app.userx, testm, 'b-', 'LineWidth', 2);
            
            hold on;
            plot(app.userx, testm, '-', 'LineWidth', 2);
            app.art_state = true;
        end
        
        %Subroutine to optimize measurement data when measure 1, 2, 3 is
        %pushed
        function results = Optimize(app)
            app.art_state = false;
            app.er_array = [];
            app.er_count = 1;
            app.art_state = false;
            g = [app.usera, app.userf2, app.userph, app.usery];
            
            %Parse through objective function to find most optimized values
            %and plot function
            om = fminsearch(@(g) ObjectiveFunc(app, g), g);         
            
            optimal_y = om(1)*sin(om(2)*app.userx + om(3))+om(4);
            hold (app.UIAxes, 'on');
            plot (app.UIAxes, app.userx, optimal_y, 'b-', 'LineWidth', 2);
            ErrorPlot(app);
        end
        
        %subroutine to plot error data in a UI plot
        function results = ErrorPlot(app)
            globalcount = 2:1:app.er_count;
            plot (app.ErrorWindow, globalcount, app.er_array(globalcount), 'r-', 'LineWidth', 2)  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: InitializeArduinoMenu
        function InitializeArduinoMenuSelected(app, event)
            app.list.Visible = false;
            try
                % connect with the arduino. The class variable is Arduino.
                % The ports might be gotten from the GUI
                app.selectedport = app.list.Value;
                app.Arduino = arduino(app.selectedport,'Uno','Libraries','Ultrasonic');
                % connect to the Ultrasound sensor the class variable
                % is UltraSound.
                app.UltraSound = app.Arduino.ultrasonic('D7','D6');
                app.ResetGraph();
                
                app.initialized = true;
                msgbox ('Arduino and Ultrasound Initialized');
            catch
                % if there are hardware connection errors, then a graphical msgbox
                % displays the error and the program
                % returns to the calling GUI
                msgbox ('Port failed', 'com', 'Error');
                return;
            end
        end

        % Menu selected function: CloseArduinoMenu
        function CloseArduinoMenuSelected(app, event)
            % clear the variables for both ultrasound and Arduino
            app.UltraSound =[];
            app.Arduino=[];
            app.ResetGraph();
            app.initialized = false;
            msgbox ('Arduino and Ultrasound Disconnected');
            
        end

        % Button pushed function: ReadValuesButton
        function ReadValuesButtonPushed(app, event)
            if (app.initialized)
                %store all values in class
                app.y(app.time) =app.UltraSound.readDistance();
                hold (app.UIAxes,"off");
                plot(app.UIAxes, [1:app.time],app.y,'-','LineWidth',2); %#ok<NBRAK>
                app.time = app.time + 1;
            else
                msgbox ('initialize Arduino first');
            end
        end

        % Button pushed function: ResetButton
        function ResetGraph(app, event)
            %delete all data collected thus far
            hold(app.UIAxes, 'off');
            plot(app.UIAxes, 0, 0);
            hold(app.ErrorWindow, 'off');
            plot(app.ErrorWindow, 0, 0);
            app.time = 1;
            app.y =[];
        end

        % Button pushed function: ContinuousButton
        function ContinuousButtonPushed(app, event)
            k = 1;
            tic
            while toc <= 3
                app.y(k)=app.UltraSound.readDistance();
                hold (app.UIAxes,"off");
                app.time(k) = toc;
                app.ymf = medfilt1(app.y,17);
                plot(app.UIAxes, app.time,app.y,app.time,app.ymf,'-','LineWidth',2);
                k = k + 1;
            end
            finalk = k; %#ok<NASGU>
        end

        % Menu selected function: PortMenu
        function PortFinder(app, event)
            app.list = uidropdown(app.UIFigure, "Position", [490 580 150 30]);
            app.list.Items = app.portlist;
        end

        % Button pushed function: Measure1Button
        function measure1(app, event)
            % our model m = a*sin(f*x+ph)+ y
            %Call optimization subroutine and apply to user plots
            Optimize(app);
            app.period1=1/app.userf2;
            app.Period1EditField.Value = app.period1;
        end

        % Button pushed function: Measure2Button
        function measure2(app, event)
            % our model m = a*sin(f*x+ph)+ y
            %Call optimization subroutine and apply to user plots
            Optimize(app);
            app.period2=1/app.userf2;
            app.Period2EditField.Value = app.period2;
        end

        % Button pushed function: Measure3Button
        function measure3(app, event)
           % our model m = a*sin(f*x+ph)+ y
           %Call optimization subroutine and apply to user plots
            Optimize(app);
            app.period3 = 1/app.userf2;
            app.Period3EditField.Value = app.period3;
        end

        % Menu selected function: LoadMenu_2
        function Load(app, event)
            [fn, pn] = uigetfile ();
            filename = [pn fn];
            y = [];
            t = [];
            y2 = [];
            
            load (filename);
            app.y = y;
            app.time = t;
            app.ymf = y2;
            %app.data_collected = true;
            plot(app.UIAxes, app.time, app.y, 'g-', 'Linewidth', 1);
            hold (app.UIAxes,"on");
            plot(app.UIAxes, app.time, app.ymf, 'r-', 'Linewidth', 2);
            hold (app.UIAxes,"on");
        end

        % Menu selected function: SaveMenu_2
        function Save(app, event)
            [fn, pn] = uiputfile ();
            filename = [pn fn];
            y = app.y; t = app.time; y2 = app.ymf;
            save (filename, 'y', 't', 'y2');
            msgbox('file saved');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %Retrieve user-defined Length values
            l1 = app.Length1EditField.Value;
            l2 = app.Length2EditField.Value;
            l3 = app.Length3EditField.Value;
            
            %Convert inches to meters. Output equation is in meters
            if app.Switch.Value == 'Inches'
                l1 = l1/39.37;
                l2 = l2/39.37;
                l3 = l3/39.37;
            end
            
            %Period matrix, Length matrix. Merging the two matrices
            P = [app.Period1EditField.Value; app.Period2EditField.Value; app.Period3EditField.Value];
            L = [l1^3, l1^2, l1; l2^3, l2^2, l2; l3^3, l3^2, l3];
            PLMerge = [l1^3, l1^2, l1, P(1); l2^3, l2^2, l2, P(2); l3^3, l3^2, l3, P(3)];
            
            %Not required, but used to clean up future code
            value = app.MethodDropDown.Value;
            
            %1 represents 'Matlab inverse method'
            if value == 1
                M = L\P;
                a1 = string(M(1));
                b1 = string(M(2));
                c1 = string(M(3));
                s = @(x) M(1)*x.^3 + M(2)*x.^2 + M(3)*x;
                figure
                x = 0:0.01:0.3;
                plot(s(x));
                xlabel('Length (meters)');
                ylabel('period (seconds)');
                app.PeriodLEditField.Value = a1 + "L^3 + " + b1 + "L^2 + " + c1 + "L";
            
            %2 represents 'Naive Gauss'
            elseif value == 2
                %Forward Elimination
                Neq1 = (PLMerge(2,1)/PLMerge(1,1))*PLMerge(1,:);
                Neq2 = Neq1 - PLMerge(2,:);
                
                Neq12 = (PLMerge(3,1)/PLMerge(1,1))*PLMerge(1,:);
                Neq3 = Neq12 - PLMerge(3,:);
                
                Neq22 = (Neq3(2)/Neq2(2))*Neq2;
                Neq3 = Neq22 - Neq3;
                
                %Back substitution
                NL = [l1^3, l1^2, l1, PLMerge(1,4); Neq2; Neq3];
                
                c2 = NL(3,4)/NL(3,3);
                b2 = (NL(2,4) - NL(2,3)*c2)/(NL(2,2));
                a2 = (NL(1,4)- NL(1,2)*b2 - NL(1,3)*c2)/(NL(1,1));
                
                %Display eqution to user
                app.PeriodLEditField.Value = a2 + "L^3 + " + b2 + "L^2 + " + c2 + "L";
            
            %3 represents 'Gauss with pivoting'
            else
                %Pivoting
                %This will ensure the beginning index of each row is in order of largest to
                %smallest (abs value)
                if abs(PLMerge(2,1)) > abs(PLMerge(1,1))
                   RowHold = PLMerge(1,:);
                   PLMerge(1,:) = PLMerge(2,:);
                   PLMerge(2,:) = RowHold;
                end
                if abs(PLMerge(3,1)) > abs(PLMerge(1,1))
                   RowHold = PLMerge(1,:);
                   PLMerge(1,:) = PLMerge(3,:);
                   PLMerge(3,:) = RowHold;
                end
                if abs(PLMerge(3,1)) > abs(PLMerge(2,1))
                   RowHold = PLMerge(2,:);
                   PLMerge(2,:) = PLMerge(3,:);
                   PLMerge(3,:) = RowHold;
                end
                %Used to debug if the matrix is in correct order
                PLMerge();
                
                %Forward Elimination
                Neq1 = (PLMerge(2,1)/PLMerge(1,1))*PLMerge(1,:);
                Neq2 = Neq1 - PLMerge(2,:);
                
                Neq12 = (PLMerge(3,1)/PLMerge(1,1))*PLMerge(1,:);
                Neq3 = Neq12 - PLMerge(3,:);
                
                Neq22 = (Neq3(2)/Neq2(2))*Neq2;
                Neq3 = Neq22 - Neq3;
                
                %Back substitution
                NL = [PLMerge(1,:); Neq2; Neq3];
                
                c3 = NL(3,4)/NL(3,3);
                b3 = (NL(2,4) - NL(2,3)*c3)/(NL(2,2));
                a3 = (NL(1,4)- NL(1,2)*b3 - NL(1,3)*c3)/(NL(1,1));
                
                %Display equation to user
                app.PeriodLEditField.Value = a3 + "L^3 + " + b3 + "L^2 + " + c3 + "L";
            end
           
        end

        % Menu selected function: ManualInputModeMenu
        function ManualInputModeMenuSelected(app, event)
            app.Period1EditField.Editable = true;
            app.Period2EditField.Editable = true;
            app.Period3EditField.Editable = true;
        end

        % Value changing function: AmplitudeKnob
        function AmplitudeKnobValueChanging(app, event)
            %Allow user updating of knob values
            changingValue = event.Value;
            delete(app.userPlot)
            app.usera = changingValue;
            app.userPlot = plot(app.UIAxes, app.userx, app.usera*sin(app.userf2*app.userx + app.userph)+app.usery,'b-','LineWidth',2);
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            %Allow user updating of slider values
            changingValue = event.Value;
            delete(app.userPlot)
            app.usery = changingValue;
            app.userPlot = plot(app.UIAxes, app.userx, app.usera*sin(app.userf2*app.userx + app.userph)+app.usery,'b-','LineWidth',2);
        end

        % Value changing function: FrequencyKnob
        function FrequencyKnobValueChanging(app, event)
            %Allow user updating of knob values
            changingValue = event.Value;
            delete(app.userPlot)
            app.userf2 = changingValue;
            app.userPlot = plot(app.UIAxes, app.userx, app.usera*sin(app.userf2*app.userx + app.userph)+app.usery,'b-','LineWidth',2);
        end

        % Value changing function: PhaseKnob
        function PhaseKnobValueChanging(app, event)
            %Allow user updating of knob values
            changingValue = event.Value;
            delete(app.userPlot)
            app.userph = changingValue;
            app.userPlot = plot(app.UIAxes, app.userx, app.usera*sin(app.userf2*app.userx + app.userph)+app.usery,'b-','LineWidth',2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu_2 = uimenu(app.FileMenu);
            app.SaveMenu_2.MenuSelectedFcn = createCallbackFcn(app, @Save, true);
            app.SaveMenu_2.Tooltip = {'Saves your current graph data'};
            app.SaveMenu_2.Text = 'Save';
            app.LoadMenu_2 = uimenu(app.FileMenu);
            app.LoadMenu_2.MenuSelectedFcn = createCallbackFcn(app, @Load, true);
            app.LoadMenu_2.Tooltip = {''};
            app.LoadMenu_2.Text = 'Load';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.InitializeArduinoMenu = uimenu(app.SettingsMenu);
            app.InitializeArduinoMenu.MenuSelectedFcn = createCallbackFcn(app, @InitializeArduinoMenuSelected, true);
            app.InitializeArduinoMenu.Text = 'InitializeArduino';
            app.CloseArduinoMenu = uimenu(app.SettingsMenu);
            app.CloseArduinoMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseArduinoMenuSelected, true);
            app.CloseArduinoMenu.Text = 'CloseArduino';
            app.PortMenu = uimenu(app.SettingsMenu);
            app.PortMenu.MenuSelectedFcn = createCallbackFcn(app, @PortFinder, true);
            app.PortMenu.Text = 'Port';
            app.ManualInputModeMenu = uimenu(app.SettingsMenu);
            app.ManualInputModeMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualInputModeMenuSelected, true);
            app.ManualInputModeMenu.Tooltip = {'Manually input period values'};
            app.ManualInputModeMenu.Text = 'Manual Input Mode';
            app.OrderMenu = uimenu(app.SettingsMenu);
            app.OrderMenu.Tooltip = {'Represents the order of the model'};
            app.OrderMenu.Text = 'Order';
            app.ndMenu = uimenu(app.OrderMenu);
            app.ndMenu.Text = '2nd';
            app.rdMenu = uimenu(app.OrderMenu);
            app.rdMenu.Text = '3rd';
            app.thMenu = uimenu(app.OrderMenu);
            app.thMenu.Text = '4th';
            app.ReadValuesButton = uibutton(app.UIFigure, 'push');
            app.ReadValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadValuesButtonPushed, true);
            app.ReadValuesButton.Text = {'ReadValues'; ''};
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGraph, true);
            app.ResetButton.Text = {'Reset'; ''};
            app.ContinuousButton = uibutton(app.UIFigure, 'push');
            app.ContinuousButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuousButtonPushed, true);
            app.ContinuousButton.Text = 'Continuous';
            app.Length1EditFieldLabel = uilabel(app.UIFigure);
            app.Length1EditFieldLabel.Text = 'Length 1';
            app.Length1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Length2EditFieldLabel = uilabel(app.UIFigure);
            app.Length2EditFieldLabel.Text = 'Length 2';
            app.Length2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Length3EditFieldLabel = uilabel(app.UIFigure);
            app.Length3EditFieldLabel.Text = 'Length 3';
            app.Length3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Period1EditFieldLabel = uilabel(app.UIFigure);
            app.Period1EditFieldLabel.Text = 'Period 1';
            app.Period1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Period1EditField.Editable = 'off';
            app.Period2EditFieldLabel = uilabel(app.UIFigure);
            app.Period2EditFieldLabel.Text = 'Period 2';
            app.Period2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Period2EditField.Editable = 'off';
            app.Period3EditFieldLabel = uilabel(app.UIFigure);
            app.Period3EditFieldLabel.Text = 'Period 3';
            app.Period3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Period3EditField.Editable = 'off';
            app.Measure1Button = uibutton(app.UIFigure, 'push');
            app.Measure1Button.ButtonPushedFcn = createCallbackFcn(app, @measure1, true);
            app.Measure1Button.Text = 'Measure 1';
            app.Measure2Button = uibutton(app.UIFigure, 'push');
            app.Measure2Button.ButtonPushedFcn = createCallbackFcn(app, @measure2, true);
            app.Measure2Button.Text = 'Measure 2';
            app.Measure3Button = uibutton(app.UIFigure, 'push');
            app.Measure3Button.ButtonPushedFcn = createCallbackFcn(app, @measure3, true);
            app.Measure3Button.Text = 'Measure 3';
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Matlab inverse function', 'Gaussian naïve', 'Gaussian with Pivoting'};
            app.MethodDropDown.ItemsData = [1 2 3];
            app.MethodDropDown.Value = 2;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PeriodLEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodLEditFieldLabel.Text = 'Period(L) = ';
            app.PeriodLEditField = uieditfield(app.UIFigure, 'text');
            app.PeriodLEditField.Editable = 'off';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Inches', 'Meters'};
            app.Switch.Value = 'Inches';
            app.AmplitudeKnobLabel = uilabel(app.UIFigure);
            app.AmplitudeKnobLabel.Text = 'Amplitude';
            app.AmplitudeKnob = uiknob(app.UIFigure, 'continuous');
            app.AmplitudeKnob.Limits = [0 0.3];
            app.AmplitudeKnob.ValueChangingFcn = createCallbackFcn(app, @AmplitudeKnobValueChanging, true);
            app.AmplitudeKnob.Value = 0.025;
            app.FrequencyKnobLabel = uilabel(app.UIFigure);
            app.FrequencyKnobLabel.Text = 'Frequency';
            app.FrequencyKnob = uiknob(app.UIFigure, 'continuous');
            app.FrequencyKnob.Limits = [1 10];
            app.FrequencyKnob.ValueChangingFcn = createCallbackFcn(app, @FrequencyKnobValueChanging, true);
            app.FrequencyKnob.Value = 6;
            app.PhaseKnobLabel = uilabel(app.UIFigure);
            app.PhaseKnobLabel.Text = 'Phase';
            app.PhaseKnob = uiknob(app.UIFigure, 'continuous');
            app.PhaseKnob.Limits = [-6.3 6.3];
            app.PhaseKnob.ValueChangingFcn = createCallbackFcn(app, @PhaseKnobValueChanging, true);
            app.PhaseKnob.Value = -1.3;
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-0.5 0.5];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.YSlider.Value = 0.12;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Reading Ultrasound')
            xlabel(app.UIAxes, 'Time (seconds)')
            ylabel(app.UIAxes, 'Distance (meters)')
            zlabel(app.UIAxes, 'Z')
            app.ErrorWindow = uiaxes(app.UIFigure);
            title(app.ErrorWindow, 'Error')
            xlabel(app.ErrorWindow, 'Iterations')
            ylabel(app.ErrorWindow, 'Error')
            zlabel(app.ErrorWindow, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReadSonarv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end